classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ErrorMsg         matlab.ui.control.Label
        CalculatorLabel  matlab.ui.control.Label
        Result           matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        SecondNum        matlab.ui.control.NumericEditField
        FirstNum         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown, FirstNum, SecondNum
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.ErrorMsg.Text = "";
            switch value
                case '+'
                    app.Result.Value = app.FirstNum.Value + app.SecondNum.Value;
                case '-'
                    app.Result.Value = app.FirstNum.Value - app.SecondNum.Value;
                case '*'
                    app.Result.Value = app.FirstNum.Value * app.SecondNum.Value;
                case '/'
                    if app.SecondNum.Value == 0
                        app.Result.Value = 0;
                        app.ErrorMsg.Text = "Divison by zero!";                      
                    else
                        app.Result.Value = app.FirstNum.Value / app.SecondNum.Value;
                    end
                case '^'
                    app.Result.Value = app.FirstNum.Value ^ app.SecondNum.Value;                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FirstNum = uieditfield(app.UIFigure, 'numeric');
            app.FirstNum.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.SecondNum = uieditfield(app.UIFigure, 'numeric');
            app.SecondNum.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'+', '-', '*', '/', '^'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '+';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.Result = uieditfield(app.UIFigure, 'numeric');
            app.Result.Editable = 'off';
            app.CalculatorLabel = uilabel(app.UIFigure);
            app.CalculatorLabel.Text = 'Calculator';
            app.ErrorMsg = uilabel(app.UIFigure);
            app.ErrorMsg.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end