classdef trafficGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        ManualPanel       matlab.ui.container.Panel
        RedButton         matlab.ui.control.Button
        YellowButton      matlab.ui.control.Button
        GreenButton       matlab.ui.control.Button
        AutomaticPanel    matlab.ui.container.Panel
        SpeedSlider       matlab.ui.control.Slider
        SpeedSliderLabel  matlab.ui.control.Label
        StartButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        ardobj = arduino() % Arduino object
        Auto = false % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            writeDigitalPin(app.ardobj, 'D5', 1); %Green Led
            writeDigitalPin(app.ardobj, 'D6', 0); %Yellow Led
            writeDigitalPin(app.ardobj, 'D7', 0); %Red Led
        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
            writeDigitalPin(app.ardobj, 'D5', 0); %Green Led
            writeDigitalPin(app.ardobj, 'D6', 1); %Yellow Led
            writeDigitalPin(app.ardobj, 'D7', 0); %Red Led            
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            writeDigitalPin(app.ardobj, 'D5', 0); %Green Led
            writeDigitalPin(app.ardobj, 'D6', 0); %Yellow Led
            writeDigitalPin(app.ardobj, 'D7', 1); %Red Led            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Auto = ~app.Auto; % Toggle state
            
            if app.Auto == true
                app.ManualPanel.Enable = "off";
                app.StartButton.Text = "Stop";
            else
                app.ManualPanel.Enable = "on";
                app.StartButton.Text = "Start";                
            end
            global delay;
            while app.Auto
                delay = app.SpeedSlider.Value / 0.1;
                while app.Auto && delay > 0
                    writeDigitalPin(app.ardobj, 'D5', 1); %Green Led
                    writeDigitalPin(app.ardobj, 'D6', 0); %Yellow Led
                    writeDigitalPin(app.ardobj, 'D7', 0); %Red Led
                    pause(0.1)
                    delay = delay - 1;
                end
                
                delay = app.SpeedSlider.Value / 0.1;
                while app.Auto && delay > 0
                    writeDigitalPin(app.ardobj, 'D5', 0); %Green Led
                    writeDigitalPin(app.ardobj, 'D6', 1); %Yellow Led
                    writeDigitalPin(app.ardobj, 'D7', 0); %Red Led
                    pause(0.1)
                    delay = delay - 1;
                end
    
                delay = app.SpeedSlider.Value / 0.1;
                while app.Auto && delay > 0
                    writeDigitalPin(app.ardobj, 'D5', 0); %Green Led
                    writeDigitalPin(app.ardobj, 'D6', 0); %Yellow Led
                    writeDigitalPin(app.ardobj, 'D7', 1); %Red Led
                    pause(0.1)
                    delay = delay - 1;
                end
            end
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.Auto = false;
            app.StartButtonPushed()

        end

        % Value changing function: SpeedSlider
        function SpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            global delay;

            app.SpeedSlider.Value = changingValue;
            delay = app.SpeedSlider.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AutomaticPanel = uipanel(app.GridLayout);
            app.AutomaticPanel.Title = 'Automatic';
            app.StartButton = uibutton(app.AutomaticPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SpeedSliderLabel = uilabel(app.AutomaticPanel);
            app.SpeedSliderLabel.Text = 'Speed Slider';
            app.SpeedSlider = uislider(app.AutomaticPanel);
            app.SpeedSlider.Limits = [0.1 1];
            app.SpeedSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedSliderValueChanging, true);
            app.SpeedSlider.Value = 0.1;
            app.ManualPanel = uipanel(app.GridLayout);
            app.ManualPanel.Title = 'Manual';
            app.GreenButton = uibutton(app.ManualPanel, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.YellowButton = uibutton(app.ManualPanel, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uibutton(app.ManualPanel, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trafficGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end